classdef scse17038_stps_comparison < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Voice1Path               matlab.ui.control.EditField
        UploadVoice1Button       matlab.ui.control.Button
        Voice2Path               matlab.ui.control.EditField
        UploadVoice2Button       matlab.ui.control.Button
        FramesliceSliderLabel    matlab.ui.control.Label
        FramesliceSlider         matlab.ui.control.Slider
        ShortTermPowerSpectrumComparisonLabel  matlab.ui.control.Label
        PlayVoice2Button         matlab.ui.control.Button
        PlayAlignedVoice1Button  matlab.ui.control.Button
        PlayAlignedVoice2Button  matlab.ui.control.Button
        PlayVoice1Button         matlab.ui.control.Button
        AlignedWaveformGraph     matlab.ui.control.UIAxes
        PowerSpectrumGraph       matlab.ui.control.UIAxes
        ViewButtonGroup          matlab.ui.container.ButtonGroup
        BothVoicesButton         matlab.ui.control.RadioButton
        Voice1OnlyButton         matlab.ui.control.RadioButton
        Voice2OnlyButton         matlab.ui.control.RadioButton
    end


    properties (Access = private)
        InputFileName1              % Stores filename of first audio file as a string
        InputFileName2              % Stores filename of second audio file as a string
        InputPathName1              % Stores the path to the folder containing the first audio file
        InputPathName2              % Stores the path to the folder containing the second audio file
        InputPath1                  % Stores the full path leading to the first audio file
        InputPath2                  % Stores the full path leading to the second audio file
        OutputPath1                 % Stores the full path leading to the first aligned audio file
        OutputPath2                 % Stores the full path leading to the second aligned audio file
        
        OldWaveform1                % Array file storing data of the first audio file
        OldWaveform2                % Array file storing data of the second audio file
        
        Waveform1                   % Array file storing data of the first aligned audio file, will be plotted
                                    % on Aligned Waveform Graph.
                                    
        Waveform2                   % Array file storing data of the second aligned audio file, will be plotted
                                    % on Aligned Waveform Graph.
                                    
        PowerSpectrum1              % Matrix storing the short-term power spectrum of all frames in the first
                                    % audio file.
                                    
        PowerSpectrum2              % Matrix storing the short-term power spectrum of all frames in the second
                                    % audio file.
                                    
        PowerSpectrumdB1            % Same matrix (for the first audio file) but approximated into a
                                    % logarithmic near-dB scale, will be plotted on Logarithmic Power Spectrum
                                    % Graph.
                                    
        PowerSpectrumdB2            % Same matrix (for the second audio file) but approximated into a
                                    % logarithmic near-dB scale, will be plotted on Logarithmic Power Spectrum
                                    % Graph.
                                    
        FrameNo                     % The number of frames in the aligned audio files.                                
        FrequencyNo                 % Number of arbitrary discrete frequencies. x-axis of Logarithmic STPS.
        
        OldSamplingFrequency1       % Sampling frequency of the first audio file.
        OldSamplingFrequency2       % Sampling frequency of the second audio file.
        SamplingFrequency           % Sampling frequency of the aligned audio files.
        
        Waveform1Data               % Plotted line of the first aligned audio file.
        Waveform2Data               % Plotted line of the second aligned audio file.
        PowerSpectrum1Data          % Plotted line of the first Short-term Power Spectrum at a particular time.
        PowerSpectrum2Data          % Plotted line of the second Short-term Power Spectrum at a particular time.
        
        VLineX                      % x-coordinates of magenta vertical line on Aligned Waveform Graph.
        VLineY                      % y-coordinates of magenta vertical line on Aligned Waveform Graph.
        AlignedWaveformVLine        % Plotted line of the magenta vertical line on Aligned Waveform Graph.
        PowerSpectrumSize           % Number of Short-term Power Spectrums in an aligned audio file.
        AudioPlayer                 % Player file for audio playback.
        
        % Time-related variables for timing the moving magenta line during audio playback
        Time
        Timer
        TimeHandler
        
        DummyPlot
    end

    methods (Access = private)
    
        function plotTime(app, obj, event, time)
           % Function handles plotting the moving magenta line during audio playback.           
           delete(app.AlignedWaveformVLine);
           
           hold(app.AlignedWaveformGraph, 'on');
           
           % Form and plot the vertical line. 
           app.VLineX(1) = time;
           app.VLineX(2) = app.VLineX(1);
           app.VLineY = -1:2:1;
           app.AlignedWaveformVLine = plot(app.AlignedWaveformGraph, app.VLineX, app.VLineY, 'm');
           legend(app.AlignedWaveformGraph, [app.Waveform1Data, app.Waveform2Data], {app.InputFileName1, app.InputFileName2});
           hold(app.AlignedWaveformGraph, 'off');
        end
        
    end


    methods (Access = private)

        % Button pushed function: UploadVoice1Button
        function UploadVoice1ButtonPushed(app, event)
            % Prompt opening of .wav file.
            [app.InputFileName1, app.InputPathName1, ~] = uigetfile('*.wav','Select the wave file');
            
            app.InputPath1 = strcat(app.InputPathName1, app.InputFileName1);
            app.Voice1Path.Value = app.InputPath1;
            
            % Plot waveforms only if both audio files have been selected.
            if ((length(app.InputPath1) > 1) && (length(app.InputPath2) > 1))
                app.ShortTermPowerSpectrumComparisonLabel.Text = 'Processing audio files...';
                
                hold(app.AlignedWaveformGraph, 'off');
                
                % Delete previous waveplots.
                app.DummyPlot = plot(app.AlignedWaveformGraph, 0, 0);
                delete(app.DummyPlot);
                
                % Call duration alignment function that uses DTW on a set of features.
                [app.OutputPath1, app.OutputPath2] = dur_alignment_f(app.InputPath1, app.InputPath2);
                
                % Get data of aligned audio files.
                [app.Waveform1, app.SamplingFrequency] = audioread(app.OutputPath1);
                [app.Waveform2, app.SamplingFrequency] = audioread(app.OutputPath2);
                
                % Get number of frames in aligned audio files.
                app.FrameNo = 1:1:length(app.Waveform1);
                
                % Plot aligned audio files.
                hold(app.AlignedWaveformGraph,'on');                
                app.Waveform1Data = plot(app.AlignedWaveformGraph, app.FrameNo ./ app.SamplingFrequency, app.Waveform1, 'blue');
                app.Waveform2Data = plot(app.AlignedWaveformGraph, app.FrameNo ./ app.SamplingFrequency, app.Waveform2, 'red');
                legend(app.AlignedWaveformGraph, [app.Waveform1Data, app.Waveform2Data], {app.InputFileName1, app.InputFileName2});
                hold(app.AlignedWaveformGraph, 'off');
                
                % Add zooming feature to Aligned Waveform Graph, allowing interactive zooming on both axes.
                zoom(app.AlignedWaveformGraph,'on');
                app.ShortTermPowerSpectrumComparisonLabel.Text = 'Generating power spectra...';
                
                % Use University of Columbia library to extract short-term power spectrums of aligned audio
                % files.
                [~, ~, app.PowerSpectrum1] = melfcc(app.Waveform1, app.SamplingFrequency);
                [~, ~, app.PowerSpectrum2] = melfcc(app.Waveform2, app.SamplingFrequency);
                
                % Get the number of power spectrums.
                app.PowerSpectrumSize = size(app.PowerSpectrum2, 2);
                
                % Set slider maximum value that shows the number of frames in the aligned audio files.
                app.FramesliceSlider.Limits = [0 app.PowerSpectrumSize];
                
                app.ShortTermPowerSpectrumComparisonLabel.Text = 'Short-Term Power Spectrum Comparison';
            end
        end

        % Button pushed function: UploadVoice2Button
        function UploadVoice2ButtonPushed(app, event)
            % Prompt opening of .wav file.
            [app.InputFileName2, app.InputPathName2, ~] = uigetfile('*.wav','Select the wave file');
            
            app.InputPath2 = strcat(app.InputPathName2, app.InputFileName2);
            app.Voice2Path.Value = app.InputPath2;
            
            % Plot waveforms only if both audio files have been selected.
            if ((length(app.InputPath1) > 1) && (length(app.InputPath2) > 1))
                app.ShortTermPowerSpectrumComparisonLabel.Text = 'Processing audio files...';
 
                % Delete previous waveplots.
                delete(app.Waveform1Data);
                delete(app.Waveform2Data);
                delete(app.PowerSpectrum1Data);
                delete(app.PowerSpectrum2Data);                
                
                % Call duration alignment function that uses DTW on a set of features.
                [app.OutputPath1, app.OutputPath2] = dur_alignment_f(app.InputPath1, app.InputPath2);
                
                % Get data of aligned audio files.
                [app.Waveform1, app.SamplingFrequency] = audioread(app.OutputPath1);
                [app.Waveform2, app.SamplingFrequency] = audioread(app.OutputPath2);
                
                % Get number of frames in aligned audio files.
                app.FrameNo = 1:1:length(app.Waveform1);
                
                % Plot aligned audio files.
                hold(app.AlignedWaveformGraph,'on');                
                app.Waveform1Data = plot(app.AlignedWaveformGraph, app.FrameNo ./ app.SamplingFrequency, app.Waveform1, 'blue');
                app.Waveform2Data = plot(app.AlignedWaveformGraph, app.FrameNo ./ app.SamplingFrequency, app.Waveform2, 'red');
                legend(app.AlignedWaveformGraph, [app.Waveform1Data, app.Waveform2Data], {app.InputFileName1, app.InputFileName2});
                hold(app.AlignedWaveformGraph, 'off');
                
                % Add zooming feature to Aligned Waveform Graph, allowing interactive zooming on both axes.
                zoom(app.AlignedWaveformGraph,'on');
                app.ShortTermPowerSpectrumComparisonLabel.Text = 'Generating power spectra...';
                
                % Use University of Columbia library to extract short-term power spectrums of aligned audio
                % files.
                [~, ~, app.PowerSpectrum1] = melfcc(app.Waveform1, app.SamplingFrequency);
                [~, ~, app.PowerSpectrum2] = melfcc(app.Waveform2, app.SamplingFrequency);
                
                % Get the number of power spectrums.
                app.PowerSpectrumSize = size(app.PowerSpectrum2, 2);
                
                % Set slider maximum value that shows the number of frames in the aligned audio files.
                app.FramesliceSlider.Limits = [0 app.PowerSpectrumSize];
                
                app.ShortTermPowerSpectrumComparisonLabel.Text = 'Short-Term Power Spectrum Comparison';
            end
            
        end

        % Value changing function: FramesliceSlider
        function FramesliceSliderValueChanging(app, event)
            % Delete previous power spectrums and vertical line on Aligned Waveform Graph.
            delete(app.PowerSpectrum1Data);
            delete(app.PowerSpectrum2Data);
            delete(app.AlignedWaveformVLine);
            
            % Determine value of slider so that it rounds to an integer so the power spectrum can be displayed.
            % This logic gets the frame number based on the position of the slider.
            if (event.Value < 1)
                changingValue = 1;
            elseif (event.Value <= size(app.PowerSpectrum1, 2))
                changingValue = round(event.Value);
            else
                changingValue = size(app.PowerSpectrum1, 2);
            end
            
            % Get short-term power spectrum in particular time on a logarithmic scale.
            app.PowerSpectrumdB1 = 20 .* log10(app.PowerSpectrum1(:,changingValue) ./ (2 .* 10 .^ (-5)));
            app.PowerSpectrumdB2 = 20 .* log10(app.PowerSpectrum2(:,changingValue) ./ (2 .* 10 .^ (-5)));
            
            % Get the number of arbitrary frequencies.
            app.FrequencyNo = 1:1:length(app.PowerSpectrum1(:,changingValue));
            
            % Plot the short-term spectrum on the Logarithmic Power Spectrum Graph.
            hold(app.PowerSpectrumGraph, 'on');
            
            % Adjust based on the view mode selected.
            if ((app.BothVoicesButton.Value)||(app.Voice1OnlyButton.Value))
                app.PowerSpectrum1Data = plot(app.PowerSpectrumGraph, app.FrequencyNo, app.PowerSpectrumdB1, 'blue');
            end
            
            if ((app.BothVoicesButton.Value)||(app.Voice2OnlyButton.Value))
                app.PowerSpectrum2Data = plot(app.PowerSpectrumGraph, app.FrequencyNo, app.PowerSpectrumdB2, 'red');    
            end
            
            % Adjust based on the view mode selected.
            if (app.BothVoicesButton.Value)
                legend(app.PowerSpectrumGraph, [app.PowerSpectrum1Data, app.PowerSpectrum2Data], {app.InputFileName1, app.InputFileName2});
            elseif (app.Voice1OnlyButton.Value)
                legend(app.PowerSpectrumGraph, app.PowerSpectrum1Data, app.InputFileName1);
            else
                legend(app.PowerSpectrumGraph, app.PowerSpectrum2Data, app.InputFileName2);
            end
            
            title(app.PowerSpectrumGraph, strcat("Logarithmic Power Spectrum at Frame Slice ", int2str(changingValue)));
            hold(app.PowerSpectrumGraph, 'off');
            
            % Plot the vertical line on the Aligned Waveform to note the 'time' denoted on the slider.
            hold(app.AlignedWaveformGraph, 'on');
            app.VLineX(1) = round((changingValue ./ size(app.PowerSpectrum1, 2) .* length(app.FrameNo))) ./ app.SamplingFrequency;
            app.VLineX(2) = app.VLineX(1);
            app.VLineY = -1:2:1;
            app.AlignedWaveformVLine = plot(app.AlignedWaveformGraph, app.VLineX, app.VLineY, 'm');
            
            if (app.BothVoicesButton.Value)
                legend(app.AlignedWaveformGraph, [app.Waveform1Data, app.Waveform2Data], {app.InputFileName1, app.InputFileName2});
            elseif (app.Voice1OnlyButton.Value)
                legend(app.AlignedWaveformGraph, app.Waveform1Data, app.InputFileName1);
            else
                legend(app.AlignedWaveformGraph, app.Waveform2Data, app.InputFileName2);
            end
            
            if (app.BothVoicesButton.Value)
                legend(app.PowerSpectrumGraph, [app.PowerSpectrum1Data, app.PowerSpectrum2Data], {app.InputFileName1, app.InputFileName2});
            elseif (app.Voice1OnlyButton.Value)
                legend(app.PowerSpectrumGraph, app.PowerSpectrum1Data, app.InputFileName1);
            else
                legend(app.PowerSpectrumGraph, app.PowerSpectrum2Data, app.InputFileName2);
            end
            
            hold(app.AlignedWaveformGraph, 'off');

        end

        % Button pushed function: PlayVoice1Button
        function PlayVoice1ButtonPushed(app, event)
            % Play Voice 1
            
            load handel;
            
            [app.OldWaveform1, app.OldSamplingFrequency1] = audioread(app.InputPath1);
            
            % Create AudioPlayer object to play the voice recording.
            app.AudioPlayer = audioplayer(app.OldWaveform1, app.OldSamplingFrequency1);
            play(app.AudioPlayer);
        end

        % Button pushed function: PlayVoice2Button
        function PlayVoice2ButtonPushed(app, event)
            % Play Voice 2
            
            load handel;
            
            [app.OldWaveform2, app.OldSamplingFrequency2] = audioread(app.InputPath2);
            
            % Create AudioPlayer object to play the voice recording.
            app.AudioPlayer = audioplayer(app.OldWaveform2, app.OldSamplingFrequency2);
            play(app.AudioPlayer);
        end

        % Button pushed function: PlayAlignedVoice1Button
        function PlayAlignedVoice1ButtonPushed(app, event)
            % Play Aligned Waveform 1
            load handel;
            
            % Create AudioPlayer object to play the voice recording.
            app.AudioPlayer = audioplayer(app.Waveform1, app.SamplingFrequency);
            
            % Set up a timer to display a magenta line that moves to the right as time proceeds.
            app.Time = 0;

            while (app.Time <= (length(app.Waveform1) / app.SamplingFrequency))
                app.Time = app.Time + 0.2;
                app.Timer = timer('StartDelay', 0.2);
                if (app.Time == 0.2)
                    app.Timer.StartFcn = @(x, y)play(app.AudioPlayer);
                end
                app.Timer.TimerFcn = {@app.plotTime, app.Time};
                start(app.Timer);
                wait(app.Timer);
                delete(app.Timer);
            end
            
            % Remove magenta vertical line.
            delete(app.AlignedWaveformVLine);
        end

        % Button pushed function: PlayAlignedVoice2Button
        function PlayAlignedVoice2ButtonPushed(app, event)
            % Play Aligned Waveform 1
            load handel;
            
            % Create AudioPlayer object to play the voice recording.
            app.AudioPlayer = audioplayer(app.Waveform2, app.SamplingFrequency);
            
            app.Time = 0;
            
            % Set up a timer to display a magenta line that moves to the right as time proceeds.
            while (app.Time <= (length(app.Waveform2) / app.SamplingFrequency))
                app.Time = app.Time + 0.2;
                app.Timer = timer('StartDelay', 0.2);
                if (app.Time == 0.2)
                    app.Timer.StartFcn = @(x, y)play(app.AudioPlayer);
                end
                app.Timer.TimerFcn = {@app.plotTime, app.Time};
                start(app.Timer);
                wait(app.Timer);
                delete(app.Timer);
            end
            
            % Remove magenta vertical line.
            delete(app.AlignedWaveformVLine);
        end

        % Selection changed function: ViewButtonGroup
        function ViewButtonGroupSelectionChanged(app, event)
            % Replot graphs according to if the user wants to see both voices, only Voice 1 aligned, or Voice 2
            % aligned.
           
            if (app.BothVoicesButton.Value)
                % Delete previous power spectrum plots.
                delete(app.PowerSpectrum1Data);
                delete(app.PowerSpectrum2Data);
                
                % Replot data.
                hold(app.AlignedWaveformGraph,'on');
                app.Waveform1Data = plot(app.AlignedWaveformGraph, app.FrameNo ./ app.SamplingFrequency, app.Waveform1, 'blue');
                app.Waveform2Data = plot(app.AlignedWaveformGraph, app.FrameNo ./ app.SamplingFrequency, app.Waveform2, 'red');
                legend(app.AlignedWaveformGraph, [app.Waveform1Data, app.Waveform2Data], {app.InputFileName1, app.InputFileName2});
                hold(app.AlignedWaveformGraph, 'off');
                
                hold(app.PowerSpectrumGraph, 'on');
                app.PowerSpectrum1Data = plot(app.PowerSpectrumGraph, app.FrequencyNo, app.PowerSpectrumdB1, 'blue');
                app.PowerSpectrum2Data = plot(app.PowerSpectrumGraph, app.FrequencyNo, app.PowerSpectrumdB2, 'red');
                legend(app.PowerSpectrumGraph, [app.PowerSpectrum1Data, app.PowerSpectrum2Data], {app.InputFileName1, app.InputFileName2});
                hold(app.PowerSpectrumGraph, 'off');
            elseif (app.Voice1OnlyButton.Value)
                % Replot data.
                app.Waveform1Data = plot(app.AlignedWaveformGraph, app.FrameNo ./ app.SamplingFrequency, app.Waveform1, 'blue');
                legend(app.AlignedWaveformGraph, app.Waveform1Data, app.InputFileName1);
                
                app.PowerSpectrum1Data = plot(app.PowerSpectrumGraph, app.FrequencyNo, app.PowerSpectrumdB1, 'blue');
                legend(app.PowerSpectrumGraph, app.PowerSpectrum1Data, app.InputFileName1);
            else
                % Replot data.
                app.Waveform2Data = plot(app.AlignedWaveformGraph, app.FrameNo ./ app.SamplingFrequency, app.Waveform2, 'red');
                legend(app.AlignedWaveformGraph, app.Waveform2Data, app.InputFileName2);
                
                app.PowerSpectrum2Data = plot(app.PowerSpectrumGraph, app.FrequencyNo, app.PowerSpectrumdB2, 'red');
                legend(app.PowerSpectrumGraph, app.PowerSpectrum2Data, app.InputFileName2);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Voice1Path = uieditfield(app.UIFigure, 'text');
            app.UploadVoice1Button = uibutton(app.UIFigure, 'push');
            app.UploadVoice1Button.ButtonPushedFcn = createCallbackFcn(app, @UploadVoice1ButtonPushed, true);
            app.UploadVoice1Button.Text = 'Upload Voice 1';
            app.Voice2Path = uieditfield(app.UIFigure, 'text');
            app.UploadVoice2Button = uibutton(app.UIFigure, 'push');
            app.UploadVoice2Button.ButtonPushedFcn = createCallbackFcn(app, @UploadVoice2ButtonPushed, true);
            app.UploadVoice2Button.Text = 'Upload Voice 2';
            app.FramesliceSliderLabel = uilabel(app.UIFigure);
            app.FramesliceSliderLabel.Text = 'Frame slice';
            app.FramesliceSlider = uislider(app.UIFigure);
            app.FramesliceSlider.Limits = [0 400];
            app.FramesliceSlider.ValueChangingFcn = createCallbackFcn(app, @FramesliceSliderValueChanging, true);
            app.FramesliceSlider.Value = 1;
            app.ShortTermPowerSpectrumComparisonLabel = uilabel(app.UIFigure);
            app.ShortTermPowerSpectrumComparisonLabel.Text = 'Short-Term Power Spectrum Comparison';
            app.PlayVoice2Button = uibutton(app.UIFigure, 'push');
            app.PlayVoice2Button.ButtonPushedFcn = createCallbackFcn(app, @PlayVoice2ButtonPushed, true);
            app.PlayVoice2Button.Text = 'Play Voice 2';
            app.PlayAlignedVoice1Button = uibutton(app.UIFigure, 'push');
            app.PlayAlignedVoice1Button.ButtonPushedFcn = createCallbackFcn(app, @PlayAlignedVoice1ButtonPushed, true);
            app.PlayAlignedVoice1Button.Text = 'Play Aligned Voice 1';
            app.PlayAlignedVoice2Button = uibutton(app.UIFigure, 'push');
            app.PlayAlignedVoice2Button.ButtonPushedFcn = createCallbackFcn(app, @PlayAlignedVoice2ButtonPushed, true);
            app.PlayAlignedVoice2Button.Text = 'Play Aligned Voice 2';
            app.PlayVoice1Button = uibutton(app.UIFigure, 'push');
            app.PlayVoice1Button.ButtonPushedFcn = createCallbackFcn(app, @PlayVoice1ButtonPushed, true);
            app.PlayVoice1Button.Text = 'Play Voice 1';
            app.AlignedWaveformGraph = uiaxes(app.UIFigure);
            title(app.AlignedWaveformGraph, 'Aligned Waveforms')
            xlabel(app.AlignedWaveformGraph, 'Time')
            ylabel(app.AlignedWaveformGraph, 'Signal')
            app.PowerSpectrumGraph = uiaxes(app.UIFigure);
            title(app.PowerSpectrumGraph, 'Logarithmic Power Spectrum at Frame Slice')
            xlabel(app.PowerSpectrumGraph, 'Frequency')
            ylabel(app.PowerSpectrumGraph, 'Logarithmic Sound Pressure Level')
            app.ViewButtonGroup = uibuttongroup(app.UIFigure);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewButtonGroupSelectionChanged, true);
            app.ViewButtonGroup.Title = 'View';
            app.BothVoicesButton = uiradiobutton(app.ViewButtonGroup);
            app.BothVoicesButton.Text = 'Both Voices';
            app.BothVoicesButton.Value = true;
            app.Voice1OnlyButton = uiradiobutton(app.ViewButtonGroup);
            app.Voice1OnlyButton.Text = 'Voice 1 Only';
            app.Voice2OnlyButton = uiradiobutton(app.ViewButtonGroup);
            app.Voice2OnlyButton.Text = 'Voice 2 Only';
        end
    end

    methods (Access = public)

        % Construct app
        function app = scse17038_stps_comparison

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end